classdef Cobblefinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        RefreshButton             matlab.ui.control.Button
        CobbleLoggedLabel         matlab.ui.control.Label
        LogCobbleButton           matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        ControlsTab               matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        TagSlider                 matlab.ui.control.Slider
        TagSliderLabel            matlab.ui.control.Label
        TagIDLabel                matlab.ui.control.Label
        PrevioustagButton         matlab.ui.control.Button
        NexttagButton             matlab.ui.control.Button
        UIAxes4                   matlab.ui.control.UIAxes
        DatalogTab                matlab.ui.container.Tab
        GridLayout3               matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        TESTINGTab                matlab.ui.container.Tab
        GridLayout6               matlab.ui.container.GridLayout
        WalkSwitch                matlab.ui.control.Switch
        WalkSwitchLabel           matlab.ui.control.Label
        HeadingKnob               matlab.ui.control.Knob
        HeadingKnobLabel          matlab.ui.control.Label
        ConfigureTab              matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        GPSSwitch_2               matlab.ui.control.Switch
        GPSSwitchLabel_2          matlab.ui.control.Label
        GPSLamp_2                 matlab.ui.control.Lamp
        GridLayout5               matlab.ui.container.GridLayout
        RSSIdotsCheckBox          matlab.ui.control.CheckBox
        ZoomRadiusmDropDownLabel  matlab.ui.control.Label
        ZoomRadiusmDropDown       matlab.ui.control.DropDown
        SnailtrailsCheckBox       matlab.ui.control.CheckBox
        MoplinesCheckBox          matlab.ui.control.CheckBox
        GPSSwitch                 matlab.ui.control.Switch
        GPSSwitchLabel            matlab.ui.control.Label
        GPSLamp                   matlab.ui.control.Lamp
        GPSCOMListBox             matlab.ui.control.ListBox
        GPSCOMSwitch              matlab.ui.control.Switch
        GPSCOMSwitchLabel         matlab.ui.control.Label
        GPSCOMLamp                matlab.ui.control.Lamp
        AntennaCOMListBox         matlab.ui.control.ListBox
        AntennaCOMSwitch          matlab.ui.control.Switch
        AntennaCOMSwitchLabel     matlab.ui.control.Label
        AntennaCOMLamp            matlab.ui.control.Lamp
        UIAxes3                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)      
        % General variables
        DSP % stuct containing boolean vars to enable display  of GUI features
        

        % Timers (and their associated variables)
        % __________
        antCOMtmr % Antenna data aquisition

        antenna % Serial obj
        antCOM % COM#
        taglist % hex list of aRFID tags to listen for
        numtags
        RSSI % Signal strength
        
        % __________
        uitmr

        GPScord % GPS position
        MOPlat % MOP Latitudes seperated by NaN values
        MOPlon % MOP longitudes seperated by NaN values
        snail % Snail trail data
        GPSplot % GPS plot struct
        mapoffset % Zoom scale (in degrees)
        RSSIscat % Scatterplot signal strength data
        tagfocus % highlight RSSI and show scatterplot of this tag index

        % __________
        wlktmr %test %debug

        heading % Direction to walk
    end
    
    methods (Access = private)     
        %% UI Update
        function UIupdate(app,src,~,~) %(app, obj, event, string_arg)
            % callback function for uitmr to update screen elements

            % 'snail' - display snail trails (previous GPS line)
            if app.DSP.snail
                app.snail(end+1,:)=app.GPScord;
                set(app.GPSplot.snail,'XData',app.snail(:,2),'YData',app.snail(:,1))
            else
                set(app.GPSplot.snail,'XData',[],'YData',[])
            end

            % RSSI dots (display signal strength at GPS coordinate)
            app.RSSIscat(end+1,:)=[app.GPScord(1),app.GPScord(2),app.RSSI']; %flag - collecting RSSI data regardless of RSSIdots setting (turn off antenna COM to disable collection)
            if app.DSP.RSSIdots
                set(app.GPSplot.RSSIdot,'Xdata',app.RSSIscat(:,2),'Ydata',app.RSSIscat(:,1),'SizeData',app.RSSIscat(:,app.tagfocus+2)+1) %.^1.5 exponent factor for short range testing
            end
            % RSSI bar graph
            set(app.GPSplot.RSSI2,'XData',[1:1:app.numtags],'YData',app.RSSIscat(end-1,3:end));
            set(app.GPSplot.RSSI,'XData',[1:1:app.numtags],'YData',app.RSSI);
            app.RSSI(:)=0; %test
%             hold(app.UIAxes4,"on")
            app.GPSplot.RSSI2.CData(:,:) = repmat([0, 0.4470, 0.7410],app.numtags,1); %test
            app.GPSplot.RSSI.CData(:,:) = repmat([0.2, 0.5, 0.7410].*1.2,app.numtags,1); %test set all to blue (eventually add green/grey for logged)
            app.GPSplot.RSSI.CData(app.tagfocus,:) = [0.8500, 0.3250, 0.0980]; % highlight in orange

            % Set map limits
            lat=[app.GPScord(1)-app.mapoffset,app.GPScord(1)+app.mapoffset];
            lon=[app.GPScord(2)-app.mapoffset,app.GPScord(2)+app.mapoffset];
            ylim(app.UIAxes3,lat)
            xlim(app.UIAxes3,lon)

            % Plot GPS cord
            set(app.GPSplot.Xmarker,'XData',app.GPScord(2),'YData',app.GPScord(1))
            set(app.GPSplot.Omarker,'XData',app.GPScord(2),'YData',app.GPScord(1))

            % Enable GPS lamp
            taskEx = get(src,'TasksExecuted');
            if mod(taskEx,2)
                app.GPSLamp.Enable='on';
                app.GPSLamp.Color='g';
            else
                app.GPSLamp.Enable='off';
            end

            drawnow limitrate; %limitrate critical
        end


        %% walking test
        function walktest(app,~,~,~)
            %test that spoofs GPS coordinates to test UIupdate

            walkspeed=5.3666e-07*1; % deg/s **See Testcars.m for calc (Lat/Lon to meters)

            app.GPScord(1)=app.GPScord(1)-(walkspeed*cosd(app.heading));
            app.GPScord(2)=app.GPScord(2)-(walkspeed*sind(app.heading));
        end


        %% lat2meters
        function degrees=meters2GPS(~,Lat,meters)
            lat_m=111e3;
            m2gps=lat_m*cosd(Lat);

            degrees=meters/m2gps;            
        end

        %% Antenna Data Aquisition
        function readant(app,src,~,~)
            a=readline(src);
            a=a{1}(2:end);
            
            % Interpret Serial & output data
            RSSI_OFFSET=255;

            [A,num_elements] = sscanf(a,'%2x%6x01',2); % parse hex to RSI & tag ID
            index = A(2) - app.taglist(1) + 1; %matlab arrays are one based,not zero based
            if num_elements==2 && index <= app.numtags && index > 0
                app.RSSI(index)=RSSI_OFFSET-A(1);
            end
        end

        %% Update taglabel
        function updatetaglabel(app)
            text = sprintf('Tag ID: %d\n(Tag # %d)',app.taglist(app.tagfocus),app.tagfocus);
            app.TagIDLabel.Text=text;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            debug=true; %enter debug mode (CTRL+F "if debug")
            
            
            %% Layout parameters
            if debug
                app.UIFigure.WindowState='normal'
                app.UIFigure.Units="normalized"
            else
                app.UIFigure.WindowState='maximized'
                app.UIFigure.Units="normalized"
            end

            %% Startup Code
            app.DSP=struct('snail',app.SnailtrailsCheckBox.Value,...
                'mop',app.MoplinesCheckBox.Value,...
                'RSSIdots',app.RSSIdotsCheckBox.Value);
            app.heading = app.HeadingKnob.Value;

            % Fix GUI elements
            app.AntennaCOMListBox.Items=cellstr(serialportlist('all'));
            app.AntennaCOMListBox.Value={};

            app.GPSCOMListBox.Items=cellstr(serialportlist('all'));
            app.GPSCOMListBox.Value={};


            % RSSI
            addpath(genpath('C:\Users\ccblack\Documents\Carson\IOD_activeRFID')) %flag hide this line when compiling, make sure all files are included in compiler options
            pause(0.05)

            % Import tag list
            [fid, msg]=fopen('taglist.txt','r');
            if fid < 0
                error('Failed to open file "%s" because: "%s"', filename, msg);
            end
            C = textscan(fid,'%s');
            app.taglist = hex2dec(C{1}); % aquire tag IDs
            fclose(fid);
            app.numtags=length(app.taglist); % # of tags tracking
            app.RSSI = zeros(app.numtags,1); % Initialize RSSI to zeros
            
            app.tagfocus=app.TagSlider.Value;
            text = sprintf('Tag ID: %d\n(Tag # %d)',app.taglist(app.tagfocus),app.tagfocus);
            app.TagIDLabel.Text=text;
            app.TagSlider.Limits=[1 app.numtags];


            %  KMl
            [app.MOPlon,app.MOPlat]=MOPelementreducer('MOPs_SD_County.kml'); % Load MOP lines
            app.GPScord=[32.927981236100734, -117.25985543852305]; %[Lat, Lon] %test -Fix static GPS cord
            app.mapoffset= meters2GPS(app,app.GPScord(1),str2double(app.ZoomRadiusmDropDown.Value));
            app.snail=app.GPScord; % Initialize snail trail
            app.snail(end+1,:)=[nan,nan]; % prevent any "snail jumps"


            % _____ Initialize graphs _____
            app.GPSplot=struct('Xmarker',{scatter(app.UIAxes3,[],[])},...
                'Omarker',{scatter(app.UIAxes3,[],[])},...
                'MOP',{plot(app.UIAxes3,[],[])},...
                'snail',{plot(app.UIAxes3,[],[])},...
                'RSSIdot',{scatter(app.UIAxes3,[],[])},...
                'RSSI',{bar(app.UIAxes4,[])},...
                'RSSI2',{bar(app.UIAxes4,[])});

            % RSSI histogram bar graph
            app.GPSplot.RSSI2=bar(app.UIAxes4,app.taglist,app.RSSI);
            app.GPSplot.RSSI2.FaceColor = 'flat';
            hold(app.UIAxes4,"on")
            app.GPSplot.RSSI=bar(app.UIAxes4,app.taglist,app.RSSI);
            app.GPSplot.RSSI.FaceColor = 'flat';
            ylim(app.UIAxes4,[0 160])

            % GPS plot
            app.GPSplot.Xmarker= scatter(app.UIAxes3,app.GPScord(2),app.GPScord(1),200,'blue','x','LineWidth',1);
            hold(app.UIAxes3,'on')
            app.GPSplot.Omarker= scatter(app.UIAxes3,app.GPScord(2),app.GPScord(1),200,'blue','o','LineWidth',1);

            % 'mop' - display mop lines
            if app.DSP.mop
                app.GPSplot.MOP=plot(app.UIAxes3,app.MOPlon,app.MOPlat,'red');
            end

            % 'snail' - display snail trails (previous GPS line)
            if app.DSP.snail
                app.snail(end+1,:)=app.GPScord;
                app.GPSplot.snail=plot(app.UIAxes3,app.snail(:,2),app.snail(:,1),'green');
            end

            % RSSI dots
            app.RSSIscat=ones(1,2+app.numtags);
            app.RSSIscat(1:2)=app.GPScord;
            app.GPSplot.RSSIdot= scatter(app.UIAxes3,app.RSSIscat(2),app.RSSIscat(1),app.RSSIscat(3),[0.8500, 0.3250, 0.0980],'o','LineWidth',0.5);

            %% Startup timers
            %GPS timer
            app.uitmr=timer('ExecutionMode', 'FixedRate', 'Period', 1, ...
                'TimerFcn', {@app.UIupdate,app}, ...
                'StartFcn',"disp('start UI timer')",...
                'StopFcn',"disp('stop UI timer')",...
                'ErrorFcn',"disp('UI timer erorr!')");
            start(app.uitmr)

            % Walk timer %test
            app.wlktmr=timer('ExecutionMode', 'FixedRate', 'Period', 0.05, ...
                        'TimerFcn', {@app.walktest,app}, ...
                        'StartFcn',"disp('start walk timer')",...
                        'StopFcn',"disp('stop walk timer')",...
                        'ErrorFcn',"disp('Walk timer erorr!')");


            %% Debug startup
            if debug
                % Start RSSI plotting
                app.AntennaCOMListBox.Value="COM4";
                app.AntennaCOMSwitch.Value="On";
                AntennaCOMListBoxValueChanged(app);
                AntennaCOMSwitchValueChanged(app);

                % set Zoom
                app.ZoomRadiusmDropDown.Value="50";
                ZoomRadiusmDropDownValueChanged(app);

                % Start wlktmr
                app.WalkSwitch.Value='On';
                WalkSwitchValueChanged(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(timerfind) % stop all timers
            delete(timerfind) % delete all timers
            delete(app)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            a=get(app.uitmr,'Running');

            if isequal(a,'off')
                refresh=timer("ExecutionMode","singleShot",...
                    "TimerFcn",{@app.UIupdate,app});
                start(refresh);
                delete(refresh);
            end
            GPSSwitchValueChanged(app)
        end

        % Value changed function: AntennaCOMListBox
        function AntennaCOMListBoxValueChanged(app, event)
            app.antCOM = app.AntennaCOMListBox.Value;
            app.AntennaCOMSwitch.Enable='on';
        end

        % Value changed function: AntennaCOMSwitch
        function AntennaCOMSwitchValueChanged(app, event)
            value = app.AntennaCOMSwitch.Value;
            switch value
                case 'On'
                    % Configure Serial
                    if any(strcmp(serialportlist('available'),app.antCOM))
                        app.antenna=serialport(app.antCOM,9600);
                        configureTerminator(app.antenna,93); % 93 ascii = ']'
                        configureCallback(app.antenna,"terminator",@app.readant);
                    else
                        error('Antenna COM port not available (%s)',app.antCOM);
                    end
                    
                    app.AntennaCOMLamp.Enable='on';
                    app.AntennaCOMLamp.Color='g';
                    app.AntennaCOMListBox.Enable='off';
                case 'Off'
%                     app.antenna=[];
                    delete(app.antenna);
                    
                    app.AntennaCOMLamp.Enable='off';
                    app.AntennaCOMLamp.Color='r';
                    app.AntennaCOMListBox.Enable='on';
            end
        end

        % Value changed function: GPSSwitch
        function GPSSwitchValueChanged(app, event)
            value = app.GPSSwitch.Value;
            app.snail(end+1,:)=[nan,nan];
            switch value
                case 'On'
                    start(app.uitmr)
                    app.GPSLamp.Enable='on';
                    app.GPSLamp.Color='g';
                case 'Off'
                    stop(app.uitmr)
                    app.GPSLamp.Enable='off';
                    app.GPSLamp.Color='r';
            end
        end

        % Value changed function: SnailtrailsCheckBox
        function SnailtrailsCheckBoxValueChanged(app, event)
            app.DSP.snail=app.SnailtrailsCheckBox.Value;
            app.snail(end+1,:)=[nan,nan];
        end

        % Value changed function: MoplinesCheckBox
        function MoplinesCheckBoxValueChanged(app, event)
            app.DSP.mop=app.MoplinesCheckBox.Value;

            if app.DSP.mop
                set(app.GPSplot.MOP,'XData',app.MOPlon,'YData',app.MOPlat)
            else
                set(app.GPSplot.MOP,'XData',[],'YData',[])
            end
        end

        % Value changed function: RSSIdotsCheckBox
        function RSSIdotsCheckBoxValueChanged(app, event)
           app.DSP.RSSIdots=app.RSSIdotsCheckBox.Value;

           if app.DSP.RSSIdots
                
            else
                set(app.GPSplot.RSSIdot,'XData',[],'YData',[],'SizeData',[])
            end
        end

        % Value changed function: ZoomRadiusmDropDown
        function ZoomRadiusmDropDownValueChanged(app, event)
            app.mapoffset= meters2GPS(app,app.GPScord(1),str2double(app.ZoomRadiusmDropDown.Value)/2);
            drawnow
        end

        % Value changed function: WalkSwitch
        function WalkSwitchValueChanged(app, event)
            value = app.WalkSwitch.Value;
            switch value
                case 'On'
                    start(app.wlktmr)
                case 'Off'
                    stop(app.wlktmr)
            end
        end

        % Value changed function: HeadingKnob
        function HeadingKnobValueChanged(app, event)
            app.heading = app.HeadingKnob.Value;
        end

        % Button pushed function: NexttagButton
        function NexttagButtonPushed(app, event)
            if app.tagfocus==app.numtags
                app.tagfocus=1;
            else
                app.tagfocus=app.tagfocus+1;
            end

            % Update label
            updatetaglabel(app)

            app.TagSlider.Value=app.tagfocus;
        end

        % Button pushed function: PrevioustagButton
        function PrevioustagButtonPushed(app, event)
            if app.tagfocus==1
                app.tagfocus=app.numtags;
            else
                app.tagfocus=app.tagfocus-1;
            end

            % Update label
            updatetaglabel(app)

            app.TagSlider.Value=app.tagfocus;
        end

        % Value changed function: TagSlider
        function TagSliderValueChanged(app, event)
            app.tagfocus = round(app.TagSlider.Value);

            % Update label
            updatetaglabel(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'GPS')
            xlabel(app.UIAxes3, 'Longitude')
            ylabel(app.UIAxes3, 'Latitude')
            zlabel(app.UIAxes3, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ControlsTab = uitab(app.TabGroup);
            app.ControlsTab.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsTab);
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'RSSI')
            xlabel(app.UIAxes4, 'Tag ID')
            ylabel(app.UIAxes4, 'Signal Strength')
            zlabel(app.UIAxes4, 'Z')
            app.NexttagButton = uibutton(app.GridLayout2, 'push');
            app.NexttagButton.ButtonPushedFcn = createCallbackFcn(app, @NexttagButtonPushed, true);
            app.NexttagButton.Text = 'Next tag';
            app.PrevioustagButton = uibutton(app.GridLayout2, 'push');
            app.PrevioustagButton.ButtonPushedFcn = createCallbackFcn(app, @PrevioustagButtonPushed, true);
            app.PrevioustagButton.Text = 'Previous tag';
            app.TagIDLabel = uilabel(app.GridLayout2);
            app.TagIDLabel.Text = 'Tag ID: ';
            app.TagSliderLabel = uilabel(app.GridLayout2);
            app.TagSliderLabel.Text = 'Tag #';
            app.TagSlider = uislider(app.GridLayout2);
            app.TagSlider.Limits = [1 30];
            app.TagSlider.ValueChangedFcn = createCallbackFcn(app, @TagSliderValueChanged, true);
            app.TagSlider.Value = 1;
            app.DatalogTab = uitab(app.TabGroup);
            app.DatalogTab.Title = 'Datalog';
            app.GridLayout3 = uigridlayout(app.DatalogTab);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TESTINGTab = uitab(app.TabGroup);
            app.TESTINGTab.Title = 'TESTING';
            app.GridLayout6 = uigridlayout(app.TESTINGTab);
            app.HeadingKnobLabel = uilabel(app.GridLayout6);
            app.HeadingKnobLabel.Text = 'Heading';
            app.HeadingKnob = uiknob(app.GridLayout6, 'continuous');
            app.HeadingKnob.Limits = [0 360];
            app.HeadingKnob.ValueChangedFcn = createCallbackFcn(app, @HeadingKnobValueChanged, true);
            app.HeadingKnob.Value = 180;
            app.WalkSwitchLabel = uilabel(app.GridLayout6);
            app.WalkSwitchLabel.Text = 'Walk';
            app.WalkSwitch = uiswitch(app.GridLayout6, 'slider');
            app.WalkSwitch.ValueChangedFcn = createCallbackFcn(app, @WalkSwitchValueChanged, true);
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.GridLayout4 = uigridlayout(app.ConfigureTab);
            app.AntennaCOMLamp = uilamp(app.GridLayout4);
            app.AntennaCOMLamp.Enable = 'off';
            app.AntennaCOMSwitchLabel = uilabel(app.GridLayout4);
            app.AntennaCOMSwitchLabel.Text = 'Antenna COM';
            app.AntennaCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.AntennaCOMSwitch.ValueChangedFcn = createCallbackFcn(app, @AntennaCOMSwitchValueChanged, true);
            app.AntennaCOMSwitch.Enable = 'off';
            app.AntennaCOMListBox = uilistbox(app.GridLayout4);
            app.AntennaCOMListBox.Items = {'list COM ports'};
            app.AntennaCOMListBox.ValueChangedFcn = createCallbackFcn(app, @AntennaCOMListBoxValueChanged, true);
            app.AntennaCOMListBox.Value = 'list COM ports';
            app.GPSCOMLamp = uilamp(app.GridLayout4);
            app.GPSCOMLamp.Enable = 'off';
            app.GPSCOMSwitchLabel = uilabel(app.GridLayout4);
            app.GPSCOMSwitchLabel.Text = 'GPS COM';
            app.GPSCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSCOMSwitch.Enable = 'off';
            app.GPSCOMListBox = uilistbox(app.GridLayout4);
            app.GPSCOMListBox.Items = {'list COM ports'};
            app.GPSCOMListBox.Enable = 'off';
            app.GPSCOMListBox.Value = 'list COM ports';
            app.GPSLamp = uilamp(app.GridLayout4);
            app.GPSSwitchLabel = uilabel(app.GridLayout4);
            app.GPSSwitchLabel.Text = 'UI update';
            app.GPSSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSSwitch.ValueChangedFcn = createCallbackFcn(app, @GPSSwitchValueChanged, true);
            app.GPSSwitch.Value = 'On';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MoplinesCheckBox = uicheckbox(app.GridLayout5);
            app.MoplinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoplinesCheckBoxValueChanged, true);
            app.MoplinesCheckBox.Text = 'Mop lines';
            app.MoplinesCheckBox.Value = true;
            app.SnailtrailsCheckBox = uicheckbox(app.GridLayout5);
            app.SnailtrailsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SnailtrailsCheckBoxValueChanged, true);
            app.SnailtrailsCheckBox.Text = 'Snail trails';
            app.SnailtrailsCheckBox.Value = true;
            app.ZoomRadiusmDropDown = uidropdown(app.GridLayout5);
            app.ZoomRadiusmDropDown.Items = {'50', '100', '200', '500', '750', '1000'};
            app.ZoomRadiusmDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoomRadiusmDropDownValueChanged, true);
            app.ZoomRadiusmDropDown.Value = '200';
            app.ZoomRadiusmDropDownLabel = uilabel(app.GridLayout5);
            app.ZoomRadiusmDropDownLabel.Text = 'Zoom Radius (m)';
            app.RSSIdotsCheckBox = uicheckbox(app.GridLayout5);
            app.RSSIdotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RSSIdotsCheckBoxValueChanged, true);
            app.RSSIdotsCheckBox.Text = 'RSSI dots';
            app.RSSIdotsCheckBox.Value = true;
            app.GPSLamp_2 = uilamp(app.GridLayout4);
            app.GPSSwitchLabel_2 = uilabel(app.GridLayout4);
            app.GPSSwitchLabel_2.Text = 'RSSI Plot';
            app.GPSSwitch_2 = uiswitch(app.GridLayout4, 'slider');
            app.GPSSwitch_2.Value = 'On';
            app.LogCobbleButton = uibutton(app.GridLayout, 'push');
            app.LogCobbleButton.Text = 'Log Cobble';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.CobbleLoggedLabel = uilabel(app.GridLayout7);
            app.CobbleLoggedLabel.Text = 'Cobble Logged!';
            app.RefreshButton = uibutton(app.GridLayout7, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'Refresh_icon.png';
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cobblefinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end